classdef MRFAnalysisGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        DictionaryGenerationTab     matlab.ui.container.Tab
        T2RangemsEditField          matlab.ui.control.EditField
        T2RangemsEditFieldLabel     matlab.ui.control.Label
        T1RangemsEditField          matlab.ui.control.EditField
        T1RangemsEditFieldLabel     matlab.ui.control.Label
        ParallelProcessingCheckBox  matlab.ui.control.CheckBox
        StartSimulationButton       matlab.ui.control.Button
        LoadFAPatternButton         matlab.ui.control.Button
        TEmsEditField               matlab.ui.control.NumericEditField
        TEmsEditFieldLabel          matlab.ui.control.Label
        TRmsEditField               matlab.ui.control.NumericEditField
        TRmsEditFieldLabel          matlab.ui.control.Label
        UIAxes2                     matlab.ui.control.UIAxes
        Tab                         matlab.ui.container.Tab
        LoadReferenceDataButton     matlab.ui.control.Button
        LoadMRFDataButton           matlab.ui.control.Button
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        Tab2                        matlab.ui.container.Tab
    end

    
    properties (Access = private)
        T1Range; % Description
        T2Range;
        B1Range = 1

    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function LoadMRFDataButtonPushed(app, event)
            [file,pth] = uigetfile();
            disp(fullfile(pth,file));
            resStruct = open(fullfile(pth,file));
            % Check that file has MRF T1 Map, MRF T2 map, Ref T1 Map and
            % Ref T2 Map
            if (isfield(resStruct,"T1Map_MRF") ~= 1)
                error("No MRF T1 map present");
            end

            if (isfield(resStruct,"T2Map_MRF") ~= 1)
                error("No MRF T2 map present");
            end
            
            if (isfield(resStruct,"T1Map_Ref") ~= 1)
                error("No reference T1 map present");
            end

            if (isfield(resStruct,"T2Map_Ref") ~= 1)
                error("No reference T2 map present");
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DictionaryGenerationTab = uitab(app.TabGroup);
            app.DictionaryGenerationTab.Title = 'Dictionary Generation';
            app.UIAxes2 = uiaxes(app.DictionaryGenerationTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.TRmsEditFieldLabel = uilabel(app.DictionaryGenerationTab);
            app.TRmsEditFieldLabel.Text = 'TR (ms)';
            app.TRmsEditField = uieditfield(app.DictionaryGenerationTab, 'numeric');
            app.TRmsEditField.Limits = [0 Inf];
            app.TRmsEditField.Value = 10;
            app.TEmsEditFieldLabel = uilabel(app.DictionaryGenerationTab);
            app.TEmsEditFieldLabel.Text = 'TE (ms)';
            app.TEmsEditField = uieditfield(app.DictionaryGenerationTab, 'numeric');
            app.TEmsEditField.Limits = [0 Inf];
            app.TEmsEditField.Value = 2;
            app.LoadFAPatternButton = uibutton(app.DictionaryGenerationTab, 'push');
            app.LoadFAPatternButton.Text = 'Load FA Pattern';
            app.StartSimulationButton = uibutton(app.DictionaryGenerationTab, 'push');
            app.StartSimulationButton.Text = 'Start Simulation';
            app.ParallelProcessingCheckBox = uicheckbox(app.DictionaryGenerationTab);
            app.ParallelProcessingCheckBox.Text = 'Parallel Processing';
            app.T1RangemsEditFieldLabel = uilabel(app.DictionaryGenerationTab);
            app.T1RangemsEditFieldLabel.Text = 'T1 Range (ms)';
            app.T1RangemsEditField = uieditfield(app.DictionaryGenerationTab, 'text');
            app.T1RangemsEditField.Value = '100:10:3000';
            app.T2RangemsEditFieldLabel = uilabel(app.DictionaryGenerationTab);
            app.T2RangemsEditFieldLabel.Text = 'T2 Range (ms)';
            app.T2RangemsEditField = uieditfield(app.DictionaryGenerationTab, 'text');
            app.T2RangemsEditField.Value = '1:1:200';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.Tab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.LoadMRFDataButton = uibutton(app.Tab, 'push');
            app.LoadMRFDataButton.Text = 'Load MRF Data';
            app.LoadReferenceDataButton = uibutton(app.Tab, 'push');
            app.LoadReferenceDataButton.Text = 'Load ReferenceData';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MRFAnalysisGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end